classdef HomePage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GAMEOVERLabel              matlab.ui.control.Label
        CountdownTimerLabel        matlab.ui.control.Label
        ReturntoHomePageButton     matlab.ui.control.Button
        Image8                     matlab.ui.control.Image
        YellowButton               matlab.ui.control.Button
        PurpleButton               matlab.ui.control.Button
        RedButton                  matlab.ui.control.Button
        PinkButton                 matlab.ui.control.Button
        GreenButton                matlab.ui.control.Button
        BlueButton                 matlab.ui.control.Button
        Image7                     matlab.ui.control.Image
        Image6                     matlab.ui.control.Image
        Image5                     matlab.ui.control.Image
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        TakethequizButton          matlab.ui.control.Button
        ClickLetsPlaytobeginLabel  matlab.ui.control.Label
        LetsPlayButton             matlab.ui.control.Button
        CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel  matlab.ui.control.Label
        BrainGameLabel             matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LetsPlayButton
        function LetsPlayButtonPushed(app, event)
            % % app.ClickLetsPlaytobeginLabel.Visible = 'off'; % Hide the label
            % app.LetsPlayButton.Visible = 'off'; % Hide the button
            % app.CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel.Visible = 'off'; % Hide the label
            % app.GamePagePanel.Visible = 'on'; % Show the game page panel
            app.ClickLetsPlaytobeginLabel.Text = 'Click "Lets Play!" to begin';
             app.Image8.Visible="on";
             app.Image8.Enable = "on";
            image1 = 'resized_red_image.jpg';
            image2 = 'resized_yellow.jpg';
            image3 = 'resized_green.jpg';
            image4 = 'resized_blue.jpg';
            image5 = 'resized_purple.jpg';
            image6 = 'resized_pink.jpg';
          
            % Display each image for 2 seconds
                images = {image1, image2, image3, image4, image5, image6};
            % Randomize the order of the images 
                randomImages = images(randperm(numel(images)));

                for i = 1:numel(images)
                    app.Image8.ImageSource = images{i};
                    %subplot(1, 1, 1);
                    %imshow(images{i});
                    %title(sprintf('Image %d', i));
                    pause(2); % Pause for 2 seconds
                end
            app.Image8.Visible="off";
            app.Image8.Enable = "off";
            app.ClickLetsPlaytobeginLabel.Text = 'Now click "Take Quiz"';
        end

        % Button pushed function: TakethequizButton
        function TakeQuizButton(app, event)
            app.LetsPlayButton.Visible = 'off'; % Hide the button
            app.LetsPlayButton.Enable = 'off';
            app.TakethequizButton.Visible = 'off'; % Hide the button
            app.TakethequizButton.Enable = 'off';
            app.ClickLetsPlaytobeginLabel.Text = 'Click the new images';
            app.CountdownTimerLabel.Visible='on';
            app.CountdownTimerLabel.Enable='on';
            image1 = 'resized_red_image.jpg';
            image2 = 'resized_yellow.jpg';
            image3 = 'resized_green.jpg';
            image4 = 'resized_blue.jpg';
            image5 = 'resized_purple.jpg';
            image6 = 'resized_pink.jpg';
            edited_image1 = 'resized_light_red.jpg';
            edited_image2 = 'resized_dark_yellow.jpg';
            edited_image3 = 'resized_light_green.jpg';
            edited_image4 = 'resized_dark_blue.jpg';
            edited_image5 = 'resized_light_purple.jpg';
            edited_image6 = 'resized_darkened_pink.jpg';


    for i = 2:7
        images = {image1, image2, image3, image4, image5, image6};
        randomImages = images(randperm(numel(images)));
        randomIndex = randi(numel(randomImages));
      
        % Set the image source to the corresponding edited image
        app.(sprintf('Image%d', i)).ImageSource = randomImages{randomIndex};
        app.(sprintf('Image%d', i)).Visible = 'on';
        app.(sprintf('Image%d', i)).Enable = 'on';
        
        randomImages(randomIndex) = [];

        
    end
    
        % Get a random index for edited images

        image_indexes = [2,3,4,5,6,7];
        j = randi(numel(image_indexes));
        edited_index = image_indexes(j) - 1;
        edited_images = {edited_image1, edited_image2, edited_image3, edited_image4, edited_image5, edited_image6};

    
         % Set the image source to the corresponding edited image
        app.(sprintf('Image%d', j)).ImageSource = edited_images{edited_index};
        app.(sprintf('Image%d', j)).Visible = 'on';
        app.(sprintf('Image%d', j)).Enable = 'on';

          %adapted code from: https://peterscarfe.com/countdownTimer.html 

            % Randomly seed the random number generation
            rng('shuffle');

             % We will be presenting each of our numbers 10 through 0 for one seconds
              % each
            presSecs = 1;

            % Starting number
            currentNumber = 10;

            % We use a while loop to count down. On each iteration of the loop we use a
            % pause value greater than 1 so that each number is presented for one
            % second
            while currentNumber >= 0

             %Convert our current number to display into a string
                numbersString = num2str(currentNumber);
             
             %Display the number in our Countdown Label
                app.CountdownTimerLabel.Text = numbersString;
    
             % Force MATLAB to update the figure window
                    drawnow;

              % Pause for one second
                    pause(presSecs);

                % Increment the number
                    currentNumber = currentNumber - 1;
                    %Hide the timer if it reaches 0
                    if currentNumber == 0
                        app.CountdownTimerLabel.Visible='off';
                        app.CountdownTimerLabel.Enable='off';

                        %Hide all the images of the quiz
                        for i = 2:7

                           image1 = 'resized_red_image.jpg';
                            image2 = 'resized_yellow.jpg';
                            image3 = 'resized_green.jpg';
                            image4 = 'resized_blue.jpg';
                            image5 = 'resized_purple.jpg';
                            image6 = 'resized_pink.jpg';


                            images = {image1, image2, image3, image4, image5, image6};
                            randomImages = images(randperm(numel(images)));
                            randomIndex = randi(numel(randomImages));

                    
                            app.(sprintf('Image%d', i)).ImageSource = randomImages{randomIndex};
                            app.(sprintf('Image%d', i)).Visible = 'off';
                            app.(sprintf('Image%d', i)).Enable = 'off';
                    end


                        %Display Too slow label for 1 sec
                        app.GAMEOVERLabel.Visible='on';
                        app.GAMEOVERLabel.Enable='on';
                        pause(1)
                        app.GAMEOVERLabel.Visible='off';
                        app.GAMEOVERLabel.Enable='off';

                    %Make the Let's Play button and Take the quiz button visible
                        app.LetsPlayButton.Visible="on";
                        app.LetsPlayButton.Enable="on";
                        app.TakethequizButton.Visible="on";
                        app.TakethequizButton.Enable="on";
                        app.ClickLetsPlaytobeginLabel.Text = 'Click "Lets Play" to begin';
                    % 
                    %  %Hide the Return to home page button 
                    %     app.ReturntoHomePageButton.Visible="off";
                    %     app.ReturntoHomePageButton.Enable="off";
                    % 
                    %   %Hide all the images of the quiz
                    %     for i = 2:7
                    % 
                    %        image1 = 'resized_red_image.jpg';
                    %         image2 = 'resized_yellow.jpg';
                    %         image3 = 'resized_green.jpg';
                    %         image4 = 'resized_blue.jpg';
                    %         image5 = 'resized_purple.jpg';
                    %         image6 = 'resized_pink.jpg';
                    % 
                    % 
                    %         images = {image1, image2, image3, image4, image5, image6};
                    %         randomImages = images(randperm(numel(images)));
                    %         randomIndex = randi(numel(randomImages));
                    % 
                    % 
                    %         app.(sprintf('Image%d', i)).ImageSource = randomImages{randomIndex};
                    %         app.(sprintf('Image%d', i)).Visible = 'off';
                    %         app.(sprintf('Image%d', i)).Enable = 'off';
                    % end
                    end
                end


        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
           clicked_image = app.Image2.ImageSource;
            edited_image1 = 'resized_light_red.jpg';
            edited_image2 = 'resized_dark_yellow.jpg';
            edited_image3 = 'resized_light_green.jpg';
            edited_image4 = 'resized_dark_blue.jpg';
            edited_image5 = 'resized_light_purple.jpg';
            edited_image6 = 'resized_darkened_pink.jpg';
            edited_images = {edited_image1, edited_image2, edited_image3, edited_image4, edited_image5, edited_image6};
       
    % Check if the clicked image is from edited_images
    if any(strcmp(edited_images, clicked_image))
        % Display 'Correct!' popup
        uialert(app.UIFigure, 'Correct!', 'Message', 'icon', 'success');
        %Display 'back" button to restart game
        app.ReturntoHomePageButton.Visible="on";
        app.ReturntoHomePageButton.Enable="on";
        app.ClickLetsPlaytobeginLabel.Text = 'Thanks for playing!';
    else
        % Display 'Incorrect!' popup
        uialert(app.UIFigure, 'Incorrect!', 'Message', 'icon', 'error');
    end


        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            clicked_image = app.Image3.ImageSource;
            edited_image1 = 'resized_light_red.jpg';
            edited_image2 = 'resized_dark_yellow.jpg';
            edited_image3 = 'resized_light_green.jpg';
            edited_image4 = 'resized_dark_blue.jpg';
            edited_image5 = 'resized_light_purple.jpg';
            edited_image6 = 'resized_darkened_pink.jpg';
            edited_images = {edited_image1, edited_image2, edited_image3, edited_image4, edited_image5, edited_image6};
       
    % Check if the clicked image is from edited_images
    if any(strcmp(edited_images, clicked_image))
        % Display 'Correct!' popup
        uialert(app.UIFigure, 'Correct!', 'Message', 'icon', 'success');
        %Display 'back" button to restart game
        app.ReturntoHomePageButton.Visible="on";
        app.ReturntoHomePageButton.Enable="on";
        app.ClickLetsPlaytobeginLabel.Text = 'Thanks for playing!';
    else
        % Display 'Incorrect!' popup
        uialert(app.UIFigure, 'Incorrect!', 'Message', 'icon', 'error');
    end
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            clicked_image = app.Image4.ImageSource;
            edited_image1 = 'resized_light_red.jpg';
            edited_image2 = 'resized_dark_yellow.jpg';
            edited_image3 = 'resized_light_green.jpg';
            edited_image4 = 'resized_dark_blue.jpg';
            edited_image5 = 'resized_light_purple.jpg';
            edited_image6 = 'resized_darkened_pink.jpg';
            edited_images = {edited_image1, edited_image2, edited_image3, edited_image4, edited_image5, edited_image6};
       
    % Check if the clicked image is from edited_images
    if any(strcmp(edited_images, clicked_image))
        % Display 'Correct!' popup
        uialert(app.UIFigure, 'Correct!', 'Message', 'icon', 'success');
        %Display 'back" button to restart game
        app.ReturntoHomePageButton.Visible="on";
        app.ReturntoHomePageButton.Enable="on";
        app.ClickLetsPlaytobeginLabel.Text = 'Thanks for playing!';
    else
        % Display 'Incorrect!' popup
        uialert(app.UIFigure, 'Incorrect!', 'Message', 'icon', 'error');
    end
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            clicked_image = app.Image5.ImageSource;
            edited_image1 = 'resized_light_red.jpg';
            edited_image2 = 'resized_dark_yellow.jpg';
            edited_image3 = 'resized_light_green.jpg';
            edited_image4 = 'resized_dark_blue.jpg';
            edited_image5 = 'resized_light_purple.jpg';
            edited_image6 = 'resized_darkened_pink.jpg';
            edited_images = {edited_image1, edited_image2, edited_image3, edited_image4, edited_image5, edited_image6};
       
    % Check if the clicked image is from edited_images
    if any(strcmp(edited_images, clicked_image))
        % Display 'Correct!' popup
        uialert(app.UIFigure, 'Correct!', 'Message', 'icon', 'success');
        %Display 'back" button to restart game
        app.ReturntoHomePageButton.Visible="on";
        app.ReturntoHomePageButton.Enable="on";
        app.ClickLetsPlaytobeginLabel.Text = 'Thanks for playing!';
    else
        % Display 'Incorrect!' popup
        uialert(app.UIFigure, 'Incorrect!', 'Message', 'icon', 'error');
    end
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            clicked_image = app.Image6.ImageSource;
            edited_image1 = 'resized_light_red.jpg';
            edited_image2 = 'resized_dark_yellow.jpg';
            edited_image3 = 'resized_light_green.jpg';
            edited_image4 = 'resized_dark_blue.jpg';
            edited_image5 = 'resized_light_purple.jpg';
            edited_image6 = 'resized_darkened_pink.jpg';
            edited_images = {edited_image1, edited_image2, edited_image3, edited_image4, edited_image5, edited_image6};
       
    % Check if the clicked image is from edited_images
    if any(strcmp(edited_images, clicked_image))
        % Display 'Correct!' popup
        uialert(app.UIFigure, 'Correct!', 'Message', 'icon', 'success');
        %Display 'back" button to restart game
        app.ReturntoHomePageButton.Visible="on";
        app.ReturntoHomePageButton.Enable="on";
        app.ClickLetsPlaytobeginLabel.Text = 'Thanks for playing!';
    else
        % Display 'Incorrect!' popup
        uialert(app.UIFigure, 'Incorrect!', 'Message', 'icon', 'error');
    end
        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            clicked_image = app.Image7.ImageSource;
            edited_image1 = 'resized_light_red.jpg';
            edited_image2 = 'resized_dark_yellow.jpg';
            edited_image3 = 'resized_light_green.jpg';
            edited_image4 = 'resized_dark_blue.jpg';
            edited_image5 = 'resized_light_purple.jpg';
            edited_image6 = 'resized_darkened_pink.jpg';
            edited_images = {edited_image1, edited_image2, edited_image3, edited_image4, edited_image5, edited_image6};
       
    % Check if the clicked image is from edited_images
    if any(strcmp(edited_images, clicked_image))
        % Display 'Correct!' popup
        uialert(app.UIFigure, 'Correct!', 'Message', 'icon', 'success');
        %Display 'back" button to restart game
        app.ReturntoHomePageButton.Visible="on";
        app.ReturntoHomePageButton.Enable="on";
        app.ClickLetsPlaytobeginLabel.Text = 'Thanks for playing!';
    else
        % Display 'Incorrect!' popup
        uialert(app.UIFigure, 'Incorrect!', 'Message', 'icon', 'error');
    end
    
        end

        % Button pushed function: ReturntoHomePageButton
        function GoBackHome(app, event)
            %Make the Let's Play button and Take the quiz button visible
            app.LetsPlayButton.Visible="on";
            app.LetsPlayButton.Enable="on";
            app.TakethequizButton.Visible="on";
            app.TakethequizButton.Enable="on";
            app.ClickLetsPlaytobeginLabel.Text = 'Click "Lets Play" to begin';
            %Hide the Return to home page button 
             app.ReturntoHomePageButton.Visible="off";
             app.ReturntoHomePageButton.Enable="off";
            %Hide the countdown
            app.CountdownTimerLabel.Visible='off';
            app.CountdownTimerLabel.Enable="off";

                %Hide all the images of the quiz
                for i = 2:7

                    image1 = 'resized_red_image.jpg';
                    image2 = 'resized_yellow.jpg';
                    image3 = 'resized_green.jpg';
                    image4 = 'resized_blue.jpg';
                    image5 = 'resized_purple.jpg';
                    image6 = 'resized_pink.jpg';


                    images = {image1, image2, image3, image4, image5, image6};
                    randomImages = images(randperm(numel(images)));
                    randomIndex = randi(numel(randomImages));

                    
                    app.(sprintf('Image%d', i)).ImageSource = randomImages{randomIndex};
                    app.(sprintf('Image%d', i)).Visible = 'off';
                    app.(sprintf('Image%d', i)).Enable = 'off';
                end

       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BackgroundHome.png');
            app.BrainGameLabel = uilabel(app.UIFigure);
            app.BrainGameLabel.Text = 'Brain Game ';
            app.CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel = uilabel(app.UIFigure);
            app.CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel.Text = 'Created by Kareena Sandhu, Salina-Mari Mentor, and Machaela Cruz';
            app.LetsPlayButton = uibutton(app.UIFigure, 'push');
            app.LetsPlayButton.ButtonPushedFcn = createCallbackFcn(app, @LetsPlayButtonPushed, true);
            app.LetsPlayButton.Text = 'Let''s Play!';
            app.ClickLetsPlaytobeginLabel = uilabel(app.UIFigure);
            app.ClickLetsPlaytobeginLabel.Text = 'Click "Let''s Play!" to begin';
            app.TakethequizButton = uibutton(app.UIFigure, 'push');
            app.TakethequizButton.ButtonPushedFcn = createCallbackFcn(app, @TakeQuizButton, true);
            app.TakethequizButton.Text = 'Take the quiz!';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'purple_image.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'lighter_red_image.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'yellow_image.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'blue_image.jpg');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'green_image.png');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'pink_image.jpg');
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.Visible = 'off';
            app.BlueButton.Text = 'Blue';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.Visible = 'off';
            app.GreenButton.Text = 'Green';
            app.PinkButton = uibutton(app.UIFigure, 'push');
            app.PinkButton.Visible = 'off';
            app.PinkButton.Text = 'Pink';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.Visible = 'off';
            app.RedButton.Text = 'Red';
            app.PurpleButton = uibutton(app.UIFigure, 'push');
            app.PurpleButton.Visible = 'off';
            app.PurpleButton.Text = 'Purple';
            app.YellowButton = uibutton(app.UIFigure, 'push');
            app.YellowButton.Visible = 'off';
            app.YellowButton.Text = 'Yellow';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.Enable = 'off';
            app.Image8.Visible = 'off';
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'BackgroundHome.png');
            app.ReturntoHomePageButton = uibutton(app.UIFigure, 'push');
            app.ReturntoHomePageButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackHome, true);
            app.ReturntoHomePageButton.Enable = 'off';
            app.ReturntoHomePageButton.Visible = 'off';
            app.ReturntoHomePageButton.Text = 'Return to Home Page';
            app.CountdownTimerLabel = uilabel(app.UIFigure);
            app.CountdownTimerLabel.Enable = 'off';
            app.CountdownTimerLabel.Visible = 'off';
            app.CountdownTimerLabel.Text = 'Countdown Timer';
            app.GAMEOVERLabel = uilabel(app.UIFigure);
            app.GAMEOVERLabel.Enable = 'off';
            app.GAMEOVERLabel.Visible = 'off';
            app.GAMEOVERLabel.Text = 'GAME OVER!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HomePage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end